classdef GUI2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        BandejaTab                     matlab.ui.container.Tab
        GUIBandejaLabel                matlab.ui.control.Label
        Geometria                      matlab.ui.container.Panel
        ImageCuadrado                  matlab.ui.control.Image
        ImageHexagono                  matlab.ui.control.Image
        LadoCuadradommSliderLabel      matlab.ui.control.Label
        LadoCuadradoSlider             matlab.ui.control.Slider
        LadoHexagonommSliderLabel      matlab.ui.control.Label
        LadoHexagonoSlider             matlab.ui.control.Slider
        AlturammEditFieldLabel         matlab.ui.control.Label
        AlturammEditField              matlab.ui.control.EditField
        Pack1                          matlab.ui.container.Panel
        GeometraseleccionadaLabel      matlab.ui.control.Label
        CuadradoLabel                  matlab.ui.control.Label
        HexagonoLabel                  matlab.ui.control.Label
        SeleccionaelmaterialparatenerunaestimacindelamasakgLabel  matlab.ui.control.Label
        HazclickenlaconfiguracinquedeseesLabel  matlab.ui.control.Label
        ImageHexagonoMontada           matlab.ui.control.Image
        EstimacionMasa                 matlab.ui.control.NumericEditField
        Mnimo100mmLabel                matlab.ui.control.Label
        Material                       matlab.ui.container.Panel
        MATERIALESLabel                matlab.ui.control.Label
        PanelAluminio                  matlab.ui.container.Panel
        ALUMINIO6061CheckBox           matlab.ui.control.CheckBox
        PropiedadesAluminio            matlab.ui.control.TextArea
        PanelCompuesto                 matlab.ui.container.Panel
        CFRPCheckBox                   matlab.ui.control.CheckBox
        PropiedadesCompuesto           matlab.ui.control.TextArea
        ImageCuadradoMontada           matlab.ui.control.Image
        IralapestaadeseparadoresparaseleccionarsuspropiedadesLabel  matlab.ui.control.Label
        SeparadoresTab                 matlab.ui.container.Tab
        GUISeparadoresLabel            matlab.ui.control.Label
        Geometria_5                    matlab.ui.container.Panel
        Separador_Aluminio             matlab.ui.control.Image
        Hazclickenelmaterialdelabandejadelmdulo3Label  matlab.ui.control.Label
        Aluminio7075CheckBox           matlab.ui.control.CheckBox
        CompuestoCFRPCheckBox          matlab.ui.control.CheckBox
        Separador_Compuesto            matlab.ui.control.Image
        IralapestaadeordenadorparaseleccionarsuspropiedadesLabel  matlab.ui.control.Label
        OrdenadorTab                   matlab.ui.container.Tab
        GUIOrdenadorLabel              matlab.ui.control.Label
        Geometria_2                    matlab.ui.container.Panel
        ImageISIS                      matlab.ui.control.Image
        ImageMICROSAT                  matlab.ui.control.Image
        SeleccionaelmaterialparatenerunaestimacindelamasaLabel_2  matlab.ui.control.Label
        HazclickenelordenadorquedeseesLabel  matlab.ui.control.Label
        ISISOnBoardComputerLabel       matlab.ui.control.Label
        MICROSATPROSpaceQualifiedProcessorUnitLabel  matlab.ui.control.Label
        Pack1_2                        matlab.ui.container.Panel
        OrdenadorseleccionadoLabel     matlab.ui.control.Label
        MICROSATLabel                  matlab.ui.control.Label
        ISISLabel                      matlab.ui.control.Label
        EstimacionMasa_2               matlab.ui.control.NumericEditField
        Material_2                     matlab.ui.container.Panel
        MATERIALESPSEUDOBANDEJALabel   matlab.ui.control.Label
        PanelAluminio_2                matlab.ui.container.Panel
        MaterialCompuestoAluminioCheckBox  matlab.ui.control.CheckBox
        NcleoPanel                     matlab.ui.container.Panel
        HexagonalLabel                 matlab.ui.control.Label
        mmLabel                        matlab.ui.control.Label
        AluminiumHoneycombLabel        matlab.ui.control.Label
        PropiedadesCompuestoAluminio   matlab.ui.control.TextArea
        PielesPanel                    matlab.ui.container.Panel
        ALUMINIO2024T85Label_2         matlab.ui.control.Label
        mmLabel_2                      matlab.ui.control.Label
        PanelCompuesto_2               matlab.ui.container.Panel
        MaterialCompuestoCFRPCheckBox  matlab.ui.control.CheckBox
        PropiedadesCompuestoCFRP       matlab.ui.control.TextArea
        TODOLISTOButton                matlab.ui.control.Button
        TakemetoCATIAButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: ImageCuadrado
        function ImageCuadradoClicked(app, event)
            app.LadoCuadradoSlider.Visible = 'on';
            app.LadoCuadradommSliderLabel.Visible = 'on';
            app.LadoHexagonoSlider.Visible = 'off';
            app.LadoHexagonommSliderLabel.Visible = 'off';
            app.Pack1.Visible = 'on';
            app.CuadradoLabel.Visible = 'on';
            app.HexagonoLabel.Visible = 'off';  
            app.ImageCuadradoMontada.Visible = 'on';
            app.ImageCuadrado.Visible = 'off';
            app.ImageHexagonoMontada.Visible = 'off';
            app.ImageHexagono.Visible = 'on';
        end

        % Image clicked function: ImageHexagono
        function ImageHexagonoClicked(app, event)
            app.LadoCuadradoSlider.Visible = 'off';
            app.LadoCuadradommSliderLabel.Visible = 'off';
            app.LadoHexagonoSlider.Visible = 'on';
            app.LadoHexagonommSliderLabel.Visible = 'on';
            app.Pack1.Visible = 'on';
            app.CuadradoLabel.Visible = 'off';
            app.HexagonoLabel.Visible = 'on';
            app.ImageCuadradoMontada.Visible = 'off';
            app.ImageCuadrado.Visible = 'on';
            app.ImageHexagonoMontada.Visible = 'on';
            app.ImageHexagono.Visible = 'off';
        end

        % Value changed function: ALUMINIO6061CheckBox
        function ALUMINIO6061CheckBoxValueChanged(app, event)
            value = app.ALUMINIO6061CheckBox.Value;
     
            switch value
                case 0
                app.CFRPCheckBox.Value = 1; 
                app.PropiedadesAluminio.Visible = 'off';
                app.PropiedadesCompuesto.Visible = 'on'; 
                if app.ImageCuadrado.Visible == 'on'
                       app.EstimacionMasa.Value = 3*10^(-5)*(app.LadoCuadradoSlider.Value)^2 + 0.0002*app.LadoCuadradoSlider.Value - 0.1305;
                    else
                        app.EstimacionMasa.Value = 2*10^(-5)*(app.LadoCuadradoSlider.Value)^2 - 0.0009*app.LadoCuadradoSlider.Value + 0.1221;
                 end
                case 1
                app.CFRPCheckBox.Value = 0; 
                app.PropiedadesAluminio.Visible = 'on';
                app.PropiedadesCompuesto.Visible = 'off';
                %%% Estimar masa Aluminio Cuadrado
                    if app.ImageCuadrado.Visible == 'on'
                       app.EstimacionMasa.Value = 0.014*app.LadoCuadradoSlider.Value - 1.5883;
                    else
                        app.EstimacionMasa.Value = 0.0093*app.LadoHexagonoSlider.Value - 0.8467;
                    end
            end
            
        end

        % Value changed function: CFRPCheckBox
        function CFRPCheckBoxValueChanged(app, event)
            value = app.CFRPCheckBox.Value;
            switch value
                case 0
                app.ALUMINIO6061CheckBox.Value = 1; 
                app.PropiedadesCompuesto.Visible = 'off';
                app.PropiedadesAluminio.Visible = 'on';
                 if app.ImageCuadrado.Visible == 'on'
                       app.EstimacionMasa.Value = 0.014*app.LadoCuadradoSlider.Value - 1.5883;
                    else
                        app.EstimacionMasa.Value = 0.0093*app.LadoHexagonoSlider.Value - 0.8467;
                 end   
                case 1
                app.ALUMINIO6061CheckBox.Value = 0; 
                app.PropiedadesCompuesto.Visible = 'on';
                app.PropiedadesAluminio.Visible = 'off';
                if app.ImageCuadrado.Visible == 'on'
                       app.EstimacionMasa.Value = 3*10^(-5)*(app.LadoCuadradoSlider.Value)^2 + 0.0002*app.LadoCuadradoSlider.Value - 0.1305;
                    else
                        app.EstimacionMasa.Value = 2*10^(-5)*(app.LadoCuadradoSlider.Value)^2 - 0.0009*app.LadoCuadradoSlider.Value + 0.1221;
                end
            end
            
        end

        % Callback function
        function TakemetoCATIAButtonPushed(app, event)


        end

        % Image clicked function: ImageISIS
        function ImageISISClicked(app, event)
            app.ISISLabel.Visible = 'on';
            app.MICROSATLabel.Visible = 'off'; 
        end

        % Image clicked function: ImageMICROSAT
        function ImageMICROSATClicked(app, event)
            app.ISISLabel.Visible = 'off';
            app.MICROSATLabel.Visible = 'on'; 
        end

        % Button pushed function: TakemetoCATIAButton
        function TakemetoCATIAButtonPushed2(app, event)
            app.TakemetoCATIAButton.Visible = 'off';
            app.TODOLISTOButton.Visible = 'on';
            
            %%%%% Guardar lado seleccionado
            if app.ImageCuadradoMontada.Visible == 'on'
                Geometria = 'Cuadrado';
                Lado_Bandeja = app.LadoCuadradoSlider.Value;
            else
                Geometria = 'Hexagono';
                Lado_Bandeja = app.LadoHexagonoSlider.Value;
            end
            
            %%%%% Guardar altura seleccionada
            Altura = app.AlturammEditField.Value;
            
            if Altura == ' '
                Altura = 160;
            end
            
            %%%%% Guardar material bandeja seleccionado
            if app.ALUMINIO6061CheckBox.Value == 1
                Material_Bandeja = 'M2_Al_6061';
            else
                Material_Bandeja = 'M2_CFRP';
            end
            
            %%%%% Guardar material pseudobandeja seleccionado
            if app.PropiedadesCompuestoCFRP.Visible == 'on'
                Material_Pseudobandeja = 'Mat_comp_mon';
            else
                Material_Pseudobandeja = 'Mat_comp_al';
            end
            
            %%%%% Guardar opcion ordenador
            if app.ISISLabel.Visible == 'on'
                Ordenador = 'A';
            else
                Ordenador = 'B';
            end
            
            %%%%% Guardar opcion separadores
            if app.Separador_Aluminio.Visible == 'on'
                Separador = 'Aluminio';
            else
                Separador = 'Compuesto';
            end
            
filename = 'M2_DesignTable';
%A = {Geometria; Lado_Bandeja; Altura; Material_Bandeja; Separador; Material_Pseudobandeja; Ordenador};
A = {Lado_Bandeja; Geometria; Altura; Material_Bandeja; Material_Pseudobandeja; Ordenador; Separador};
sheet = 2;
xlRange = 'B1';
xlswrite(filename,A,sheet,xlRange)  

        end

        % Value changed function: MaterialCompuestoAluminioCheckBox
        function MaterialCompuestoAluminioCheckBoxValueChanged(app, event)
            value = app.MaterialCompuestoAluminioCheckBox.Value;
            
            switch value
                case 0
                app.MaterialCompuestoCFRPCheckBox.Value = 1; 
                app.PropiedadesCompuestoAluminio.Visible = 'off';
                app.PropiedadesCompuestoCFRP.Visible = 'on';
                app.NcleoPanel.Visible = 'off';
                app.PielesPanel.Visible = 'off';
                if app.ISISLabel.Visible == 'on' % ORDENADOR A
                    if app.LadoCuadradoSlider.Value < 381 || app.LadoHexagonoSlider < 381
                        app.EstimacionMasa_2.Value = 0.014;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                else % ORDENADOR B
                    if app.LadoCuadradoSlider.Value == 400 || app.LadoHexagonoSlider == 400
                        app.EstimacionMasa_2.Value = 0.022;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                end
                
                case 1
                app.MaterialCompuestoCFRPCheckBox.Value = 0; 
                app.PropiedadesCompuestoAluminio.Visible = 'on';
                app.PropiedadesCompuestoCFRP.Visible = 'off'; 
                app.NcleoPanel.Visible = 'on';
                app.PielesPanel.Visible = 'on';
                %%% Estimar masa Aluminio 
                if app.ISISLabel.Visible == 'on' % ORDENADOR A
                    if app.LadoCuadradoSlider.Value < 381 || app.LadoHexagonoSlider < 381
                        app.EstimacionMasa_2.Value = 0.020;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                else % ORDENADOR B
                    if app.LadoCuadradoSlider.Value == 400 || app.LadoHexagonoSlider == 400
                        app.EstimacionMasa_2.Value = 0.021;
                    else
                        app.EstimacionMasa_2.Value = 0.020;
                    end
                end
                        
            end
            
        end

        % Value changed function: MaterialCompuestoCFRPCheckBox
        function MaterialCompuestoCFRPCheckBoxValueChanged(app, event)
            value = app.MaterialCompuestoCFRPCheckBox.Value;
            
           switch value
                case 0
                app.MaterialCompuestoAluminioCheckBox.Value = 1; 
                app.PropiedadesCompuestoCFRP.Visible = 'off';
                app.PropiedadesCompuestoAluminio.Visible = 'on';
                app.NcleoPanel.Visible = 'on';
                app.PielesPanel.Visible = 'on';
                if app.ISISLabel.Visible == 'on' % ORDENADOR A
                    if app.LadoCuadradoSlider.Value < 381 || app.LadoHexagonoSlider < 381
                        app.EstimacionMasa_2.Value = 0.020;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                else % ORDENADOR B
                    if app.LadoCuadradoSlider.Value == 400 || app.LadoHexagonoSlider == 400
                        app.EstimacionMasa_2.Value = 0.021;
                    else
                        app.EstimacionMasa_2.Value = 0.020;
                    end
                end
                        
                case 1
                app.MaterialCompuestoAluminioCheckBox.Value = 0; 
                app.PropiedadesCompuestoCFRP.Visible = 'on';
                app.PropiedadesCompuestoAluminio.Visible = 'off';
                app.NcleoPanel.Visible = 'off';
                app.PielesPanel.Visible = 'off';
                if app.ISISLabel.Visible == 'on' % ORDENADOR A
                    if app.LadoCuadradoSlider.Value < 381 || app.LadoHexagonoSlider < 381
                        app.EstimacionMasa_2.Value = 0.014;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                else % ORDENADOR B
                    if app.LadoCuadradoSlider.Value == 400 || app.LadoHexagonoSlider == 400
                        app.EstimacionMasa_2.Value = 0.022;
                    else
                        app.EstimacionMasa_2.Value = 0.021;
                    end
                end
                
                     
           end
            
        end

        % Value changed function: Aluminio7075CheckBox
        function Aluminio7075CheckBoxValueChanged(app, event)
            value = app.Aluminio7075CheckBox.Value;
            
            switch value
                case 0
                app.CompuestoCFRPCheckBox.Value = 1; 
                app.Separador_Aluminio.Visible = 'off';
                app.Separador_Compuesto.Visible = 'on';
                case 1
                app.CompuestoCFRPCheckBox.Value = 0; 
                app.Separador_Aluminio.Visible = 'on';
                app.Separador_Compuesto.Visible = 'off';
           end
        end

        % Value changed function: CompuestoCFRPCheckBox
        function CompuestoCFRPCheckBoxValueChanged(app, event)
            value = app.CompuestoCFRPCheckBox.Value;
            
             switch value
                case 0
                app.Aluminio7075CheckBox.Value = 1; 
                app.Separador_Aluminio.Visible = 'on';
                app.Separador_Compuesto.Visible = 'on';
                case 1
                app.Aluminio7075CheckBox.Value = 0; 
                app.Separador_Aluminio.Visible = 'off';
                app.Separador_Compuesto.Visible = 'on';
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.BandejaTab = uitab(app.TabGroup);
            app.BandejaTab.Title = 'Bandeja';
            app.GUIBandejaLabel = uilabel(app.BandejaTab);
            app.GUIBandejaLabel.Text = 'GUI Bandeja';
            app.Geometria = uipanel(app.BandejaTab);
            app.ImageCuadrado = uiimage(app.Geometria);
            app.ImageCuadrado.ImageClickedFcn = createCallbackFcn(app, @ImageCuadradoClicked, true);
            app.ImageCuadrado.ImageSource = 'Bandeja-cuadrada.png';
            app.ImageHexagono = uiimage(app.Geometria);
            app.ImageHexagono.ImageClickedFcn = createCallbackFcn(app, @ImageHexagonoClicked, true);
            app.ImageHexagono.ImageSource = 'Bandeja-hexagonal.png';
            app.LadoCuadradommSliderLabel = uilabel(app.Geometria);
            app.LadoCuadradommSliderLabel.Visible = 'off';
            app.LadoCuadradommSliderLabel.Text = 'Lado Cuadrado [mm]';
            app.LadoCuadradoSlider = uislider(app.Geometria);
            app.LadoCuadradoSlider.Limits = [300 400];
            app.LadoCuadradoSlider.Visible = 'off';
            app.LadoCuadradoSlider.Value = 300;
            app.LadoHexagonommSliderLabel = uilabel(app.Geometria);
            app.LadoHexagonommSliderLabel.Visible = 'off';
            app.LadoHexagonommSliderLabel.Text = 'Lado Hexagono [mm]';
            app.LadoHexagonoSlider = uislider(app.Geometria);
            app.LadoHexagonoSlider.Limits = [300 400];
            app.LadoHexagonoSlider.Visible = 'off';
            app.LadoHexagonoSlider.Value = 300;
            app.AlturammEditFieldLabel = uilabel(app.Geometria);
            app.AlturammEditFieldLabel.Text = 'Altura [mm] :';
            app.AlturammEditField = uieditfield(app.Geometria, 'text');
            app.Pack1 = uipanel(app.Geometria);
            app.Pack1.Visible = 'off';
            app.GeometraseleccionadaLabel = uilabel(app.Pack1);
            app.GeometraseleccionadaLabel.Text = 'Geometría seleccionada: ';
            app.CuadradoLabel = uilabel(app.Pack1);
            app.CuadradoLabel.Text = 'CUADRADO';
            app.HexagonoLabel = uilabel(app.Pack1);
            app.HexagonoLabel.Text = 'HEXÁGONO';
            app.SeleccionaelmaterialparatenerunaestimacindelamasakgLabel = uilabel(app.Geometria);
            app.SeleccionaelmaterialparatenerunaestimacindelamasakgLabel.Text = 'Selecciona el material para tener una estimación de la masa [kg]:';
            app.HazclickenlaconfiguracinquedeseesLabel = uilabel(app.Geometria);
            app.HazclickenlaconfiguracinquedeseesLabel.Text = 'Haz click en la configuración que desees';
            app.ImageHexagonoMontada = uiimage(app.Geometria);
            app.ImageHexagonoMontada.Visible = 'off';
            app.ImageHexagonoMontada.ImageSource = 'Hexagono_Montada.png';
            app.EstimacionMasa = uieditfield(app.Geometria, 'numeric');
            app.Mnimo100mmLabel = uilabel(app.Geometria);
            app.Mnimo100mmLabel.Text = '(Mínimo 100 mm)';
            app.Material = uipanel(app.BandejaTab);
            app.MATERIALESLabel = uilabel(app.Material);
            app.MATERIALESLabel.Text = 'MATERIALES';
            app.PanelAluminio = uipanel(app.Material);
            app.ALUMINIO6061CheckBox = uicheckbox(app.PanelAluminio);
            app.ALUMINIO6061CheckBox.ValueChangedFcn = createCallbackFcn(app, @ALUMINIO6061CheckBoxValueChanged, true);
            app.ALUMINIO6061CheckBox.Text = 'ALUMINIO 6061';
            app.PropiedadesAluminio = uitextarea(app.PanelAluminio);
            app.PropiedadesAluminio.Visible = 'off';
            app.PropiedadesAluminio.Value = {'Young Modulus : 6,89 * 10^10 N/m^2'; ''; 'Density: 2700 kg/m^3'; ''; 'Yield Strength: 2,76 * 10^8 N/m^2'; ''; 'Poisson Ratio: 0,33'};
            app.PanelCompuesto = uipanel(app.Material);
            app.CFRPCheckBox = uicheckbox(app.PanelCompuesto);
            app.CFRPCheckBox.ValueChangedFcn = createCallbackFcn(app, @CFRPCheckBoxValueChanged, true);
            app.CFRPCheckBox.Text = 'CFRP';
            app.PropiedadesCompuesto = uitextarea(app.PanelCompuesto);
            app.PropiedadesCompuesto.Visible = 'off';
            app.PropiedadesCompuesto.Value = {'Young Modulus : 2,1 * 10^11 N/m^2'; ''; 'Density: 1800 kg/m^3'; ''; 'Yield Strength: 3,80 * 10^9 N/m^2'; ''; 'Poisson Ratio: 0,02'};
            app.ImageCuadradoMontada = uiimage(app.BandejaTab);
            app.ImageCuadradoMontada.Visible = 'off';
            app.ImageCuadradoMontada.ImageSource = 'Cuadrada_Montada.png';
            app.IralapestaadeseparadoresparaseleccionarsuspropiedadesLabel = uilabel(app.BandejaTab);
            app.IralapestaadeseparadoresparaseleccionarsuspropiedadesLabel.Text = 'Ir a la pestaña de separadores para seleccionar sus propiedades ';
            app.SeparadoresTab = uitab(app.TabGroup);
            app.SeparadoresTab.Title = 'Separadores';
            app.GUISeparadoresLabel = uilabel(app.SeparadoresTab);
            app.GUISeparadoresLabel.Text = 'GUI Separadores';
            app.Geometria_5 = uipanel(app.SeparadoresTab);
            app.Separador_Aluminio = uiimage(app.Geometria_5);
            app.Separador_Aluminio.Visible = 'off';
            app.Separador_Aluminio.ImageSource = 'sep_aluminio.png';
            app.Hazclickenelmaterialdelabandejadelmdulo3Label = uilabel(app.Geometria_5);
            app.Hazclickenelmaterialdelabandejadelmdulo3Label.Text = 'Haz click en el material de la bandeja del módulo 3:';
            app.Aluminio7075CheckBox = uicheckbox(app.Geometria_5);
            app.Aluminio7075CheckBox.ValueChangedFcn = createCallbackFcn(app, @Aluminio7075CheckBoxValueChanged, true);
            app.Aluminio7075CheckBox.Text = 'Aluminio 7075 ';
            app.CompuestoCFRPCheckBox = uicheckbox(app.Geometria_5);
            app.CompuestoCFRPCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompuestoCFRPCheckBoxValueChanged, true);
            app.CompuestoCFRPCheckBox.Text = 'Compuesto CFRP';
            app.Separador_Compuesto = uiimage(app.Geometria_5);
            app.Separador_Compuesto.Visible = 'off';
            app.Separador_Compuesto.ImageSource = 'sep_compuesto.png';
            app.IralapestaadeordenadorparaseleccionarsuspropiedadesLabel = uilabel(app.SeparadoresTab);
            app.IralapestaadeordenadorparaseleccionarsuspropiedadesLabel.Text = 'Ir a la pestaña de ordenador para seleccionar sus propiedades ';
            app.OrdenadorTab = uitab(app.TabGroup);
            app.OrdenadorTab.Title = 'Ordenador';
            app.GUIOrdenadorLabel = uilabel(app.OrdenadorTab);
            app.GUIOrdenadorLabel.Text = 'GUI Ordenador';
            app.Geometria_2 = uipanel(app.OrdenadorTab);
            app.ImageISIS = uiimage(app.Geometria_2);
            app.ImageISIS.ImageClickedFcn = createCallbackFcn(app, @ImageISISClicked, true);
            app.ImageISIS.ImageSource = 'ISIS-On-board-Computer-removebg.png';
            app.ImageMICROSAT = uiimage(app.Geometria_2);
            app.ImageMICROSAT.ImageClickedFcn = createCallbackFcn(app, @ImageMICROSATClicked, true);
            app.ImageMICROSAT.ImageSource = 'Microsatpro-removebg.png';
            app.SeleccionaelmaterialparatenerunaestimacindelamasaLabel_2 = uilabel(app.Geometria_2);
            app.SeleccionaelmaterialparatenerunaestimacindelamasaLabel_2.Text = 'Selecciona el material para tener una estimación de la masa de la pseudobandeja [kg]:';
            app.HazclickenelordenadorquedeseesLabel = uilabel(app.Geometria_2);
            app.HazclickenelordenadorquedeseesLabel.Text = 'Haz click en el ordenador que desees';
            app.ISISOnBoardComputerLabel = uilabel(app.Geometria_2);
            app.ISISOnBoardComputerLabel.Text = 'ISIS On Board Computer ';
            app.MICROSATPROSpaceQualifiedProcessorUnitLabel = uilabel(app.Geometria_2);
            app.MICROSATPROSpaceQualifiedProcessorUnitLabel.Text = 'MICROSATPRO Space Qualified Processor Unit';
            app.Pack1_2 = uipanel(app.Geometria_2);
            app.OrdenadorseleccionadoLabel = uilabel(app.Pack1_2);
            app.OrdenadorseleccionadoLabel.Text = 'Ordenador seleccionado: ';
            app.MICROSATLabel = uilabel(app.Pack1_2);
            app.MICROSATLabel.Visible = 'off';
            app.MICROSATLabel.Text = 'MICROSATPRO';
            app.ISISLabel = uilabel(app.Pack1_2);
            app.ISISLabel.Visible = 'off';
            app.ISISLabel.Text = 'ISIS';
            app.EstimacionMasa_2 = uieditfield(app.Geometria_2, 'numeric');
            app.Material_2 = uipanel(app.OrdenadorTab);
            app.MATERIALESPSEUDOBANDEJALabel = uilabel(app.Material_2);
            app.MATERIALESPSEUDOBANDEJALabel.Text = 'MATERIALES PSEUDOBANDEJA';
            app.PanelAluminio_2 = uipanel(app.Material_2);
            app.MaterialCompuestoAluminioCheckBox = uicheckbox(app.PanelAluminio_2);
            app.MaterialCompuestoAluminioCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaterialCompuestoAluminioCheckBoxValueChanged, true);
            app.MaterialCompuestoAluminioCheckBox.Text = 'Material Compuesto Aluminio';
            app.NcleoPanel = uipanel(app.PanelAluminio_2);
            app.NcleoPanel.Title = 'Núcleo';
            app.NcleoPanel.Visible = 'off';
            app.HexagonalLabel = uilabel(app.NcleoPanel);
            app.HexagonalLabel.Text = '2024 Hexagonal';
            app.mmLabel = uilabel(app.NcleoPanel);
            app.mmLabel.Text = '16 mm';
            app.AluminiumHoneycombLabel = uilabel(app.NcleoPanel);
            app.AluminiumHoneycombLabel.Text = 'Aluminium Honeycomb ';
            app.PropiedadesCompuestoAluminio = uitextarea(app.PanelAluminio_2);
            app.PropiedadesCompuestoAluminio.Visible = 'off';
            app.PropiedadesCompuestoAluminio.Value = {'Young Modulus : 7,31 * 10^10 N/m^2'; ''; 'Density: 2780 kg/m^3'; ''; 'Yield Strength: 4,14 * 10^8 N/m^2'; ''; 'Poisson Ratio: 0,33'};
            app.PielesPanel = uipanel(app.PanelAluminio_2);
            app.PielesPanel.Title = 'Pieles';
            app.PielesPanel.Visible = 'off';
            app.ALUMINIO2024T85Label_2 = uilabel(app.PielesPanel);
            app.ALUMINIO2024T85Label_2.Text = 'ALUMINIO 2024-T85';
            app.mmLabel_2 = uilabel(app.PielesPanel);
            app.mmLabel_2.Text = '0,5 mm';
            app.PanelCompuesto_2 = uipanel(app.Material_2);
            app.MaterialCompuestoCFRPCheckBox = uicheckbox(app.PanelCompuesto_2);
            app.MaterialCompuestoCFRPCheckBox.ValueChangedFcn = createCallbackFcn(app, @MaterialCompuestoCFRPCheckBoxValueChanged, true);
            app.MaterialCompuestoCFRPCheckBox.Text = 'Material Compuesto CFRP';
            app.PropiedadesCompuestoCFRP = uitextarea(app.PanelCompuesto_2);
            app.PropiedadesCompuestoCFRP.Visible = 'off';
            app.PropiedadesCompuestoCFRP.Value = {'Young Modulus : 2,4 * 10^11 N/m^2'; ''; 'Density: 1800 kg/m^3'; ''; 'Yield Strength: 3,80 * 10^9 N/m^2'; ''; 'Poisson Ratio: 0,02'};
            app.TODOLISTOButton = uibutton(app.OrdenadorTab, 'push');
            app.TODOLISTOButton.Icon = 'check.png';
            app.TODOLISTOButton.Visible = 'off';
            app.TODOLISTOButton.Text = 'TODO LISTO ';
            app.TakemetoCATIAButton = uibutton(app.OrdenadorTab, 'push');
            app.TakemetoCATIAButton.ButtonPushedFcn = createCallbackFcn(app, @TakemetoCATIAButtonPushed2, true);
            app.TakemetoCATIAButton.Icon = 'logo_Catia.png';
            app.TakemetoCATIAButton.Text = 'Take me to CATIA!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end